classdef main_calculation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PoleZeroandGainPanel          matlab.ui.container.Panel
        GainEditField                 matlab.ui.control.EditField
        GainEditFieldLabel            matlab.ui.control.Label
        ZeroEditField                 matlab.ui.control.EditField
        ZeroEditFieldLabel            matlab.ui.control.Label
        PoleEditField                 matlab.ui.control.EditField
        PoleEditFieldLabel            matlab.ui.control.Label
        G2sPanel                      matlab.ui.container.Panel
        YsEditField_2                 matlab.ui.control.EditField
        YsEditField_2Label            matlab.ui.control.Label
        RsEditField_2                 matlab.ui.control.EditField
        RsEditField_2Label            matlab.ui.control.Label
        BackButton                    matlab.ui.control.Button
        ClearButton                   matlab.ui.control.Button
        CalculateButton               matlab.ui.control.Button
        GraphsPanel                   matlab.ui.container.Panel
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        TimeRangePanel                matlab.ui.container.Panel
        StopEditField                 matlab.ui.control.EditField
        StopEditFieldLabel            matlab.ui.control.Label
        StartEditField                matlab.ui.control.EditField
        StartEditFieldLabel           matlab.ui.control.Label
        OverallTransferFunctionPanel  matlab.ui.container.Panel
        DenominatorEditField          matlab.ui.control.EditField
        DenominatorEditFieldLabel     matlab.ui.control.Label
        NumeratorEditField            matlab.ui.control.EditField
        NumeratorEditFieldLabel       matlab.ui.control.Label
        G1sPanel                      matlab.ui.container.Panel
        YsEditField                   matlab.ui.control.EditField
        YsEditFieldLabel              matlab.ui.control.Label
        RsEditField                   matlab.ui.control.EditField
        RsEditFieldLabel              matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    methods (Access = private)
        function plotTheFunction(app, final)
            time_start = str2double(app.StartEditField.Value);
            time_stop = str2double(app.StopEditField.Value);
            % Control Flow
            if isempty(time_start)
                time = 0:0.01:time_stop; % If the Time Start is empty
            elseif isempty(time_stop)
                time = time_start:0.01:10; % If the Time Stop is empty
            elseif isempty(time_start) && isempty(time_stop)
                time = 0:0.01:10; % If both are empty
            elseif time_stop <= time_start
                uialert(app.UIFigure, 'Invalid input for time range', 'Error', 'Icon', 'error');
                return;
            elseif ~isempty(time_stop) && ~isempty(time_start)
                time = time_start:0.01:time_stop; % If both are not empty
            end
            % Make the Function
            step_response_func = step(time, final);
            respon_impulse_func = impulse(time, final);

            % Make the Graph
            app.UIAxes.XTick = linspace(min(time), max(time), 5);
            app.UIAxes.XTickLabel = num2str(app.UIAxes.XTick');
            app.UIAxes_2.XTick = linspace(min(time), max(time), 5);
            app.UIAxes_2.XTickLabel = num2str(app.UIAxes_2.XTick');

            % Plot the Function into UIAxes
            plot(app.UIAxes, time, step_response_func);
            plot(app.UIAxes_2, time, respon_impulse_func);
        end
        function showZPK(app, z, p, k)
            % Show the ZPK
            str2values_Pole = join(string(p), ', '); % 0,0,-2
            str2values_Zeros = join(string(z), ', ');
            str2values_Gain = join(string(k), ', ');
            app.GainEditField.Value = str2values_Gain;
            app.PoleEditField.Value = str2values_Pole;
            app.ZeroEditField.Value = str2values_Zeros;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed2(app, event)
            % Make the G1 & G2 Function Input and Output
            g1_numerator = str2num(app.YsEditField.Value);
            g1_denominator = str2num(app.RsEditField.Value);

            g2_numerator = str2num(app.YsEditField_2.Value);
            g2_denominator = str2num(app.RsEditField_2.Value);
               
            % Calculate it's Transfer Function
            first_tf = tf(g1_numerator, g1_denominator);
            second_tf = tf(g2_numerator, g2_denominator);
            total_tf = first_tf * second_tf;
            
            % Plot it's Graph
            plotTheFunction(app, total_tf);

            % Calculate the G1 & G2 with the Convolution Method
            tf_numerator = conv(g1_numerator, g2_numerator);
            tf_denominator = conv(g1_denominator, g2_denominator);
            
            % Show it's Pole, Zero, and Gain
            app.NumeratorEditField.Value = poly2str(tf_numerator, 's');
            app.DenominatorEditField.Value = poly2str(tf_denominator, 's');
            [z, p, k] = tf2zp(tf_numerator, tf_denominator);
            showZPK(app, z, p, k);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed2(app, event)
            app.GainEditField.Value = '';
            app.ZeroEditField.Value = '';
            app.PoleEditField.Value = '';
            app.DenominatorEditField.Value = '';
            app.NumeratorEditField.Value = '';
            app.YsEditField.Value = '';
            app.RsEditField.Value = '';
            app.YsEditField_2.Value = '';
            app.RsEditField_2.Value = '';
            app.StartEditField.Value = '0';
            app.StopEditField.Value = '10';
            cla(app.UIAxes_2);
            cla(app.UIAxes);
        end

        % Button pushed function: BackButton
        function BackButtonPushed2(app, event)
            main_window;
            app.UIFigure.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Backdrop App.png';
            app.G1sPanel = uipanel(app.UIFigure);
            app.G1sPanel.Title = 'G1(s)';
            app.RsEditFieldLabel = uilabel(app.G1sPanel);
            app.RsEditFieldLabel.Text = 'R(s)';
            app.RsEditField = uieditfield(app.G1sPanel, 'text');
            app.YsEditFieldLabel = uilabel(app.G1sPanel);
            app.YsEditFieldLabel.Text = 'Y(s)';
            app.YsEditField = uieditfield(app.G1sPanel, 'text');
            app.OverallTransferFunctionPanel = uipanel(app.UIFigure);
            app.OverallTransferFunctionPanel.Title = 'Overall Transfer Function';
            app.NumeratorEditFieldLabel = uilabel(app.OverallTransferFunctionPanel);
            app.NumeratorEditFieldLabel.Text = 'Numerator';
            app.NumeratorEditField = uieditfield(app.OverallTransferFunctionPanel, 'text');
            app.DenominatorEditFieldLabel = uilabel(app.OverallTransferFunctionPanel);
            app.DenominatorEditFieldLabel.Text = 'Denominator';
            app.DenominatorEditField = uieditfield(app.OverallTransferFunctionPanel, 'text');
            app.TimeRangePanel = uipanel(app.UIFigure);
            app.TimeRangePanel.Title = 'Time Range';
            app.StartEditFieldLabel = uilabel(app.TimeRangePanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.TimeRangePanel, 'text');
            app.StartEditField.Value = '0';
            app.StopEditFieldLabel = uilabel(app.TimeRangePanel);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.TimeRangePanel, 'text');
            app.StopEditField.Value = '10';
            app.GraphsPanel = uipanel(app.UIFigure);
            app.GraphsPanel.Title = 'Graphs';
            app.UIAxes = uiaxes(app.GraphsPanel);
            title(app.UIAxes, 'Step Response')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.GraphsPanel);
            title(app.UIAxes_2, 'Impulse Response')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed2, true);
            app.CalculateButton.Text = 'Calculate';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed2, true);
            app.ClearButton.Text = 'Clear';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed2, true);
            app.BackButton.Text = 'Back';
            app.G2sPanel = uipanel(app.UIFigure);
            app.G2sPanel.Title = 'G2(s)';
            app.RsEditField_2Label = uilabel(app.G2sPanel);
            app.RsEditField_2Label.Text = 'R(s)';
            app.RsEditField_2 = uieditfield(app.G2sPanel, 'text');
            app.YsEditField_2Label = uilabel(app.G2sPanel);
            app.YsEditField_2Label.Text = 'Y(s)';
            app.YsEditField_2 = uieditfield(app.G2sPanel, 'text');
            app.PoleZeroandGainPanel = uipanel(app.UIFigure);
            app.PoleZeroandGainPanel.Title = 'Pole, Zero and Gain';
            app.PoleEditFieldLabel = uilabel(app.PoleZeroandGainPanel);
            app.PoleEditFieldLabel.Text = 'Pole';
            app.PoleEditField = uieditfield(app.PoleZeroandGainPanel, 'text');
            app.ZeroEditFieldLabel = uilabel(app.PoleZeroandGainPanel);
            app.ZeroEditFieldLabel.Text = 'Zero';
            app.ZeroEditField = uieditfield(app.PoleZeroandGainPanel, 'text');
            app.GainEditFieldLabel = uilabel(app.PoleZeroandGainPanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.PoleZeroandGainPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_calculation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end