classdef main_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        SoalButton   matlab.ui.control.Button
        GuideButton  matlab.ui.control.Button
        MainButton   matlab.ui.control.Button
        ExitButton   matlab.ui.control.Button
        Image        matlab.ui.control.Image
    end

    
    properties (Access = private)
        main_calculation % Description
    end
    
    methods (Access = public)
        
        function importdata(~, ~)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_calculation)
            
        end

        % Button pushed function: MainButton
        function MainButtonPushed(app, event)
            main_calculation
            app.UIFigure.Visible = "off";
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete
        end

        % Button pushed function: GuideButton
        function GuideButtonPushed(app, event)
            main_guidetf
            app.UIFigure.Visible = "off";
        end

        % Button pushed function: SoalButton
        function SoalButtonPushed(app, event)
            main_problem
            app.UIFigure.Visible = "off";
        end

        % Callback function
        function TeoriButtonPushed(app, event)
            main_theory
            app.UIFigure.Visible = "off";
        end

        % Callback function
        function ButtonPushed(app, event)
            main_about
            app.UIFigure.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'GUI Main Menu.png';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.MainButton = uibutton(app.UIFigure, 'push');
            app.MainButton.ButtonPushedFcn = createCallbackFcn(app, @MainButtonPushed, true);
            app.MainButton.Text = 'Main';
            app.GuideButton = uibutton(app.UIFigure, 'push');
            app.GuideButton.ButtonPushedFcn = createCallbackFcn(app, @GuideButtonPushed, true);
            app.GuideButton.Text = 'Guide';
            app.SoalButton = uibutton(app.UIFigure, 'push');
            app.SoalButton.ButtonPushedFcn = createCallbackFcn(app, @SoalButtonPushed, true);
            app.SoalButton.Text = 'Soal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end